classdef mini_gland_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiniGlandUIFigure               matlab.ui.Figure
        AcinusSimulationLabel           matlab.ui.control.Label
        DuctSimulationLabel             matlab.ui.control.Label
        RunSimulationButtonDuct         matlab.ui.control.Button
        RunSimulationButtonAcinus       matlab.ui.control.Button
        ENaCconductanceLabel            matlab.ui.control.Label
        EditFieldiG_ENaC                matlab.ui.control.NumericEditField
        CFTRconductanceLabel            matlab.ui.control.Label
        EditFieldiG_CFTR                matlab.ui.control.NumericEditField
        maximumphospholipaseCLabel      matlab.ui.control.Label
        EditFieldVPLC                   matlab.ui.control.NumericEditField
        CaCCconductanceLabel            matlab.ui.control.Label
        EditFieldiG_CaCC                matlab.ui.control.NumericEditField
        MaxiKconductanceLabel           matlab.ui.control.Label
        EditFieldiG_BK                  matlab.ui.control.NumericEditField
        KconductanceLabel               matlab.ui.control.Label
        EditFieldiG_K_B                 matlab.ui.control.NumericEditField
        NaconductanceLabel              matlab.ui.control.Label
        EditFieldiG_Na_B                matlab.ui.control.NumericEditField
        ClconductanceLabel              matlab.ui.control.Label
        EditFieldiG_Cl_B                matlab.ui.control.NumericEditField
        NaKATPasedensityLabel           matlab.ui.control.Label
        EditFieldiNKA_alpha_B           matlab.ui.control.NumericEditField
        ClconductanceLabel_2            matlab.ui.control.Label
        EditFieldiG_P_Cl                matlab.ui.control.NumericEditField
        waterpermeabilityLabel          matlab.ui.control.Label
        EditFieldiL_A                   matlab.ui.control.NumericEditField
        waterpermeabilityLabel_2        matlab.ui.control.Label
        EditFieldiL_B                   matlab.ui.control.NumericEditField
        NaKATPasedensityEditFieldLabel  matlab.ui.control.Label
        EditFieldaNaK                   matlab.ui.control.NumericEditField
        fractionofNaKinthebasalmembraneEditFieldLabel  matlab.ui.control.Label
        EditFieldbNaKf                  matlab.ui.control.NumericEditField
        maximumconductanceofCaCCEditFieldLabel  matlab.ui.control.Label
        EditFieldGCl                    matlab.ui.control.NumericEditField
        basolateralmaximumconductanceofCaKCEditFieldLabel  matlab.ui.control.Label
        EditFieldGK                     matlab.ui.control.NumericEditField
        apicalwaterpermeabilityEditFieldLabel  matlab.ui.control.Label
        EditFieldLa                     matlab.ui.control.NumericEditField
        basolateralwaterpermeabilityEditFieldLabel  matlab.ui.control.Label
        EditFieldLb                     matlab.ui.control.NumericEditField
        tightjunctionalwaterpermeabilityEditFieldLabel  matlab.ui.control.Label
        EditFieldLt                     matlab.ui.control.NumericEditField
        cellstosimulateLabel            matlab.ui.control.Label
        C1Button                        matlab.ui.control.StateButton
        C2Button                        matlab.ui.control.StateButton
        C3Button                        matlab.ui.control.StateButton
        C4Button                        matlab.ui.control.StateButton
        C5Button                        matlab.ui.control.StateButton
        C6Button                        matlab.ui.control.StateButton
        C7Button                        matlab.ui.control.StateButton
        C8Button                        matlab.ui.control.StateButton
        C9Button                        matlab.ui.control.StateButton
        C10Button                       matlab.ui.control.StateButton
        C11Button                       matlab.ui.control.StateButton
        C12Button                       matlab.ui.control.StateButton
        C13Button                       matlab.ui.control.StateButton
        C14Button                       matlab.ui.control.StateButton
        acinussimulationfileLabel       matlab.ui.control.Label
        DuctInputDropDown               matlab.ui.control.DropDown
        EditFieldsG_ENaC                matlab.ui.control.NumericEditField
        intercalatedstriatedLabel       matlab.ui.control.Label
        EditFieldsG_BK                  matlab.ui.control.NumericEditField
        EditFieldsG_K_B                 matlab.ui.control.NumericEditField
        EditFieldsG_Na_B                matlab.ui.control.NumericEditField
        EditFieldsG_Cl_B                matlab.ui.control.NumericEditField
        EditFieldsNKA_alpha_B           matlab.ui.control.NumericEditField
        EditFieldsL_A                   matlab.ui.control.NumericEditField
        EditFieldsG_P_Cl                matlab.ui.control.NumericEditField
        EditFieldsL_B                   matlab.ui.control.NumericEditField
        GeneratePlotsButton             matlab.ui.control.Button
        ductfluxesCheckBox              matlab.ui.control.CheckBox
        steadystateductCheckBox         matlab.ui.control.CheckBox
        EditFieldsG_P_Na                matlab.ui.control.NumericEditField
        EditFieldsG_P_K                 matlab.ui.control.NumericEditField
        NaconductanceLabel_2            matlab.ui.control.Label
        EditFieldiG_P_Na                matlab.ui.control.NumericEditField
        KconductanceLabel_2             matlab.ui.control.Label
        EditFieldiG_P_K                 matlab.ui.control.NumericEditField
        resultsfilelabelEditFieldLabel  matlab.ui.control.Label
        resultsfilelabelEditField       matlab.ui.control.EditField
        ductsimulationfileLabel         matlab.ui.control.Label
        PlotsInputDropDown              matlab.ui.control.DropDown
        parametersfilelabelEditFieldLabel  matlab.ui.control.Label
        parametersfilelabelEditField    matlab.ui.control.EditField
        parametersfileLabel             matlab.ui.control.Label
        MyParmsInputDropDown            matlab.ui.control.DropDown
        SaveParmsButton                 matlab.ui.control.Button
        LoadParmsButton                 matlab.ui.control.Button
        ductsimulationtypeButtonGroup   matlab.ui.container.ButtonGroup
        ExVivoButton                    matlab.ui.control.RadioButton
        InVivoButton                    matlab.ui.control.RadioButton
        ResetDefaultParametersButton    matlab.ui.control.Button
        VisSimulationResultsButton      matlab.ui.control.Button
        acinussimulationfileLabel_2     matlab.ui.control.Label
        AcinusFileVisDropDown           matlab.ui.control.DropDown
        ductsimulationfileLabel_2       matlab.ui.control.Label
        DuctFileVisDropDown             matlab.ui.control.DropDown
        cmsLabel                        matlab.ui.control.Label
        cmsLabel_2                      matlab.ui.control.Label
        singleductcellCheckBox          matlab.ui.control.CheckBox
        stimulatedductCheckBox          matlab.ui.control.CheckBox
        cmsLabel_3                      matlab.ui.control.Label
        cmsLabel_4                      matlab.ui.control.Label
        cmsLabel_5                      matlab.ui.control.Label
        cmsLabel_6                      matlab.ui.control.Label
        cmsLabel_7                      matlab.ui.control.Label
        molcm2Label                     matlab.ui.control.Label
        cmsLabel_8                      matlab.ui.control.Label
        cmsLabel_9                      matlab.ui.control.Label
        cmsLabel_10                     matlab.ui.control.Label
        cmsLabel_11                     matlab.ui.control.Label
        cmsLabel_12                     matlab.ui.control.Label
        EditFieldsAE_G_A                matlab.ui.control.NumericEditField
        EditFieldsNBC_G_A               matlab.ui.control.NumericEditField
        molcm2Label_7                   matlab.ui.control.Label
        molseccm2Label                  matlab.ui.control.Label
        molseccm2Label_2                matlab.ui.control.Label
        anionexchangerdensityLabel      matlab.ui.control.Label
        EditFieldiAE_G_A                matlab.ui.control.NumericEditField
        NBCcotransporterdensityLabel    matlab.ui.control.Label
        EditFieldiNBC_G_A               matlab.ui.control.NumericEditField
        NHEcotransporterdensityLabel    matlab.ui.control.Label
        EditFieldiNHE_G_A               matlab.ui.control.NumericEditField
        EditFieldsAE_G_B                matlab.ui.control.NumericEditField
        EditFieldsNBC_G_B               matlab.ui.control.NumericEditField
        molcm2Label_5                   matlab.ui.control.Label
        molseccm2Label_3                matlab.ui.control.Label
        molseccm2Label_4                matlab.ui.control.Label
        anionexchangerdensityLabel_2    matlab.ui.control.Label
        EditFieldiAE_G_B                matlab.ui.control.NumericEditField
        NBCcotransporterdensityLabel_2  matlab.ui.control.Label
        EditFieldiNBC_G_B               matlab.ui.control.NumericEditField
        NHEcotransporterdensityLabel_2  matlab.ui.control.Label
        EditFieldiNHE_G_B               matlab.ui.control.NumericEditField
        EditFieldsG_CaCC                matlab.ui.control.NumericEditField
        EditFieldsG_CFTR                matlab.ui.control.NumericEditField
        EditFieldsNHE_G_A               matlab.ui.control.NumericEditField
        EditFieldsNHE_G_B               matlab.ui.control.NumericEditField
        ApicalLabel                     matlab.ui.control.Label
        BasolateralLabel                matlab.ui.control.Label
        TightjunctionalLabel            matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        SimulationResultsVisualisationLabel  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        GeneratePlotsButton_2           matlab.ui.control.Button
        acinuslumenfluidCheckBox        matlab.ui.control.CheckBox
        plot3CheckBox                   matlab.ui.control.CheckBox
        plot2CheckBox                   matlab.ui.control.CheckBox
        plot4CheckBox                   matlab.ui.control.CheckBox
        resultsfilelabelEditField_2Label  matlab.ui.control.Label
        resultsfilelabelEditField_2     matlab.ui.control.EditField
        acinussimulationfileLabel_3     matlab.ui.control.Label
        PlotsInputDropDown_2            matlab.ui.control.DropDown
        SaveParmsButton_2               matlab.ui.control.Button
        LoadParmsButton_2               matlab.ui.control.Button
        parametersfilelabelEditField_2Label  matlab.ui.control.Label
        parametersfilelabelEditField_2  matlab.ui.control.EditField
        parametersfileLabel_2           matlab.ui.control.Label
        MyParmsInputDropDown_2          matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        ResetDefaultParametersButton_2  matlab.ui.control.Button
        molcm2Label_8                   matlab.ui.control.Label
        cmsLabel_13                     matlab.ui.control.Label
        cmsLabel_14                     matlab.ui.control.Label
        cmsLabel_15                     matlab.ui.control.Label
        cmsLabel_16                     matlab.ui.control.Label
        cmsLabel_17                     matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        molcm2Label_9                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ini_file = 'parms_ex-vivo.ini' % default simulation parameters file name
        ini_file_acinus = 'par.mat' % default simulation parameters file name
        deployed_app_path = '~/Desktop/MiniGland/' % Description
    end
    
    methods (Access = private)
        function cells = get_sim_cells(app) % NOTE: the app parameter is always prepended
            cells = [];
            if app.C1Button.Value == 1; cells = [cells 1]; end
            if app.C2Button.Value == 1; cells = [cells 2]; end
            if app.C3Button.Value == 1; cells = [cells 3]; end
            if app.C4Button.Value == 1; cells = [cells 4]; end
            if app.C5Button.Value == 1; cells = [cells 5]; end
            if app.C6Button.Value == 1; cells = [cells 6]; end
            if app.C7Button.Value == 1; cells = [cells 7]; end
            if app.C8Button.Value == 1; cells = [cells 8]; end
            if app.C9Button.Value == 1; cells = [cells 9]; end
            if app.C10Button.Value == 1; cells = [cells 10]; end
            if app.C11Button.Value == 1; cells = [cells 11]; end
            if app.C12Button.Value == 1; cells = [cells 12]; end
            if app.C13Button.Value == 1; cells = [cells 13]; end
            if app.C14Button.Value == 1; cells = [cells 14]; end
        end
        function par = get_gui_parms_acinus(app)  % NOTE: the app parameter is always prepended
            par.VPLC = app.EditFieldVPLC.Value;
            par.aNaK = app.EditFieldaNaK.Value;
            par.bNaKf = app.EditFieldbNaKf.Value;
            par.GCl = app.EditFieldGCl.Value;
            par.GK = app.EditFieldGK.Value;
            par.La = app.EditFieldLa.Value;
            par.Lb = app.EditFieldLb.Value;
            par.Lt = app.EditFieldLt.Value;
        end
        function set_gui_parms_acinus(app, p)  % NOTE: the app parameter is always prepended
            app.EditFieldVPLC.Value = p.VPLC;
            app.EditFieldaNaK.Value = p.aNaK;
            app.EditFieldbNaKf.Value = p.bNaKf;
            app.EditFieldGCl.Value = p.GCl;
            app.EditFieldGK.Value = p.GK;
            app.EditFieldLa.Value = p.La;
            app.EditFieldLb.Value = p.Lb;
            app.EditFieldLt.Value = p.Lt;
        end
        function set_gui_parms_ini_acinus(app)
            cd('acinus');
            load(app.ini_file_acinus, 'par');
            cd('..');
            app.set_gui_parms_acinus(par);
        end
        function set_parm_files_acinus(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/myparms_*.mat").name}; % my parameters input files
            if ~isempty(files) 
                app.MyParmsInputDropDown_2.Items = [files];
            else
                app.MyParmsInputDropDown_2.Value = "- none -";
            end  
        end
        function set_plots_input_acinus(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.PlotsInputDropDown_2.Items = files;
                app.PlotsInputDropDown_2.Value = files(1);
                app.AcinusFileVisDropDown.Items = files;
                app.AcinusFileVisDropDown.Value = files(1);
            end
        end

        %function set_gui_parms(app, p_i, p_s, p_c)  % NOTE: the app parameter is always prepended
        function set_gui_parms(app, p_i, p_s)  % NOTE: the app parameter is always prepended
            % intercalated apical
            app.EditFieldiAE_G_A.Value = p_i.ae_g_a;
            app.EditFieldiG_CaCC.Value = p_i.g_cacc;
            app.EditFieldiG_CFTR.Value = p_i.g_cftr;
            app.EditFieldiG_ENaC.Value = p_i.g_enac;
            app.EditFieldiG_BK.Value = p_i.g_bk;
            app.EditFieldiNBC_G_A.Value = p_i.nbc_g_a;
            app.EditFieldiNHE_G_A.Value = p_i.nhe_g_a;
            app.EditFieldiL_A.Value = p_i.l_a;

            % intercaled basal 
            app.EditFieldiAE_G_B.Value = p_i.ae_g_b;
            app.EditFieldiG_Cl_B.Value = p_i.g_cl_b;
            app.EditFieldiG_K_B.Value = p_i.g_k_b;
            app.EditFieldiG_Na_B.Value = p_i.g_na_b;
            app.EditFieldiNKA_alpha_B.Value = p_i.nka_alpha_b;
            app.EditFieldiNBC_G_B.Value = p_i.nbc_g_b;
            app.EditFieldiNHE_G_B.Value = p_i.nhe_g_b;
            app.EditFieldiL_B.Value = p_i.l_b;

            % intercaled tight-junctional
            app.EditFieldiG_P_Cl.Value = p_i.g_p_cl;
            app.EditFieldiG_P_K.Value = p_i.g_p_k;
            app.EditFieldiG_P_Na.Value = p_i.g_p_na;

            % striated apical          
            app.EditFieldsAE_G_A.Value = p_s.ae_g_a;
            app.EditFieldsG_CaCC.Value = p_s.g_cacc;
            app.EditFieldsG_CFTR.Value = p_s.g_cftr;
            app.EditFieldsG_ENaC.Value = p_s.g_enac;
            app.EditFieldsG_BK.Value = p_s.g_bk;
            app.EditFieldsNBC_G_A.Value = p_s.nbc_g_a;
            app.EditFieldsNHE_G_A.Value = p_s.nhe_g_a;
            app.EditFieldsL_A.Value = p_s.l_a;

            % striated basal
            app.EditFieldsAE_G_B.Value = p_s.ae_g_b;
            app.EditFieldsG_Cl_B.Value = p_s.g_cl_b;
            app.EditFieldsG_K_B.Value = p_s.g_k_b;
            app.EditFieldsG_Na_B.Value = p_s.g_na_b;
            app.EditFieldsNKA_alpha_B.Value = p_s.nka_alpha_b;
            app.EditFieldsNBC_G_B.Value = p_s.nbc_g_b;
            app.EditFieldsNHE_G_B.Value = p_s.nhe_g_b;
            app.EditFieldsL_B.Value = p_s.l_b;

            % striated tight-junctional
            app.EditFieldsG_P_Cl.Value = p_s.g_p_cl;
            app.EditFieldsG_P_K.Value = p_s.g_p_k;
            app.EditFieldsG_P_Na.Value = p_s.g_p_na;
        end

        function set_gui_parms_ini(app)
            cd('duct');
            INI = ini2struct(app.ini_file);
            cd('..');
            %app.set_gui_parms(INI.intercalated, INI.striated, INI.duct_common);
            app.set_gui_parms(INI.intercalated, INI.striated);
        end

        function parms = get_gui_parms(app)  % NOTE: the app parameter is always prepended
            % intercalated apical
            p_i.ae_g_a = app.EditFieldiAE_G_A.Value;
            p_i.g_cacc = app.EditFieldiG_CaCC.Value;
            p_i.g_cftr = app.EditFieldiG_CFTR.Value;
            p_i.g_enac = app.EditFieldiG_ENaC.Value;
            p_i.g_bk = app.EditFieldiG_BK.Value;
            p_i.nbc_g_a = app.EditFieldiNBC_G_A.Value;
            p_i.nhe_g_a = app.EditFieldiNHE_G_A.Value;
            p_i.l_a = app.EditFieldiL_A.Value;

            % intercaled basal 
            p_i.ae_g_b = app.EditFieldiAE_G_B.Value;
            p_i.g_cl_b = app.EditFieldiG_Cl_B.Value;
            p_i.g_k_b = app.EditFieldiG_K_B.Value;
            p_i.g_na_b = app.EditFieldiG_Na_B.Value;
            p_i.nka_alpha_b = app.EditFieldiNKA_alpha_B.Value;
            p_i.nbc_g_b = app.EditFieldiNBC_G_B.Value;
            p_i.nhe_g_b = app.EditFieldiNHE_G_B.Value;
            p_i.l_b = app.EditFieldiL_B.Value;

            % intercaled tight-junctional
            p_i.g_p_cl = app.EditFieldiG_P_Cl.Value;
            p_i.g_p_k = app.EditFieldiG_P_K.Value;
            p_i.g_p_na = app.EditFieldiG_P_Na.Value;

            % striated apical          
            p_s.ae_g_a = app.EditFieldsAE_G_A.Value;
            p_s.g_cacc = app.EditFieldsG_CaCC.Value;
            p_s.g_cftr = app.EditFieldsG_CFTR.Value;
            p_s.g_enac = app.EditFieldsG_ENaC.Value;
            p_s.g_bk = app.EditFieldsG_BK.Value;
            p_s.nbc_g_a = app.EditFieldsNBC_G_A.Value;
            p_s.nhe_g_a = app.EditFieldsNHE_G_A.Value;
            p_s.l_a = app.EditFieldsL_A.Value;

            % striated basal
            p_s.ae_g_b = app.EditFieldsAE_G_B.Value;
            p_s.g_cl_b = app.EditFieldsG_Cl_B.Value;
            p_s.g_k_b = app.EditFieldsG_K_B.Value;
            p_s.g_na_b = app.EditFieldsG_Na_B.Value;
            p_s.nka_alpha_b = app.EditFieldsNKA_alpha_B.Value;
            p_s.nbc_g_b = app.EditFieldsNBC_G_B.Value;
            p_s.nhe_g_b = app.EditFieldsNHE_G_B.Value;
            p_s.l_b = app.EditFieldsL_B.Value;
            
            % striated tight-junctional
            p_s.g_p_cl = app.EditFieldsG_P_Cl.Value;
            p_s.g_p_k = app.EditFieldsG_P_K.Value;
            p_s.g_p_na = app.EditFieldsG_P_Na.Value;
            
            parms.p_i = p_i;
            parms.p_s = p_s;
            %parms.p_c = p_c;
        end
        function set_acinus_files(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.DuctInputDropDown.Items = files;
                app.DuctInputDropDown.Value = files(1);
                %app.DuctFileVisDropDown.Items = files;
                %app.DuctFileVisDropDown.Value = files(1);
            end
        end
        function set_plots_input(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.PlotsInputDropDown.Items = files;
                app.PlotsInputDropDown.Value = files(1);
                app.DuctFileVisDropDown.Items = files;
                app.DuctFileVisDropDown.Value = files(1);
            end
        end
        function set_parm_files(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/myparms_*.mat").name}; % my parameters input files
            if ~isempty(files) 
                app.MyParmsInputDropDown.Items = [files];
            else
                app.MyParmsInputDropDown.Value = "- none -";
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Launching MiniGland app...');
            app.set_gui_parms_ini_acinus();          
            app.set_parm_files_acinus();
            app.set_plots_input_acinus();
            app.set_acinus_files();
            app.set_gui_parms_ini();          
            app.set_parm_files();
            app.set_plots_input();
        end

        % Button pushed function: RunSimulationButtonAcinus
        function RunSimulationButtonAcinusPushed(app, event)
            disp('Running acinus simulation...');
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running acinus simulation','Indeterminate','on');
            run_acinus(app.get_sim_cells(), app.get_gui_parms_acinus(), get(app.resultsfilelabelEditField_2,'Value'));
            app.set_plots_input_acinus();
            app.set_acinus_files();
            close(d);
            disp('Acinus simulation complete');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Acinus simulation complete','Icon','success');
        end

        % Button pushed function: RunSimulationButtonDuct
        function RunSimulationButtonDuctPushed(app, event)
            if strcmp(get(app.DuctInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Running duct simulation...');
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running duct simulation','Indeterminate','on');
            run_duct(app.ini_file, app.get_gui_parms(), ...
                get(app.DuctInputDropDown,'Value'), get(app.resultsfilelabelEditField,'Value'));
            app.set_plots_input();
            close(d);
            disp('Duct simulation complete.');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Duct simulation complete','Icon','success');
        end

        % Callback function
        function ExVivoButtonPushed(app, event)
            disp('Loading ex-vivo parms.');
            app.ini_file = 'parms_ex-vivo.ini';
            app.set_gui_parms_ini();
        end

        % Callback function
        function InVivoButtonPushed(app, event)
            disp('Loading in-vivo parms.');
            app.ini_file = 'parms_in-vivo.ini';
            app.set_gui_parms_ini();
        end

        % Close request function: MiniGlandUIFigure
        function MiniGlandUIFigureCloseRequest(app, event)
            delete(app)
            disp('Exiting MiniGland app');
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            if strcmp(get(app.PlotsInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Generating duct plot(s)...');
            p.steadystateduct = app.steadystateductCheckBox.Value;
            p.singleductcell = app.singleductcellCheckBox.Value;
            p.stimulatedduct = app.stimulatedductCheckBox.Value;
            p.ductfluxes = app.ductfluxesCheckBox.Value;

            plot_duct(app.PlotsInputDropDown.Value, p);
            disp('Plot(s) completed.');
        end

        % Button pushed function: SaveParmsButton
        function SaveParmsButtonPushed(app, event)
            parms = app.get_gui_parms;
            cd('duct');
            save(strcat("myparms_",app.parametersfilelabelEditField.Value,".mat"),"parms");
            cd('..');
            app.set_parm_files();
        end

        % Button pushed function: LoadParmsButton
        function LoadParmsButtonPushed(app, event)
            if strcmp(get(app.MyParmsInputDropDown,'Value'), '- none -')
                return;
            end
            load(strcat("duct/", get(app.MyParmsInputDropDown,'Value')), "parms");
            %app.set_gui_parms(parms.p_i, parms.p_s, parms.p_c);
            app.set_gui_parms(parms.p_i, parms.p_s);
        end

        % Selection changed function: ductsimulationtypeButtonGroup
        function ductsimulationtypeButtonGroupSelectionChanged(app, event)
            if app.ExVivoButton.Value
                app.ini_file = 'parms_ex-vivo.ini';
            else
                app.ini_file = 'parms_in-vivo.ini';
            end          
            app.set_gui_parms_ini();
            app.set_parm_files();
        end

        % Button pushed function: ResetDefaultParametersButton
        function ResetDefaultParametersButtonPushed(app, event)
            app.set_gui_parms_ini();
            app.set_parm_files();            
        end

        % Button pushed function: VisSimulationResultsButton
        function VisSimulationResultsButtonPushed(app, event)
            if strcmp(get(app.DuctFileVisDropDown,'Value'), '- none -')
                return;
            end
            disp('Converting duct data...');
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Converting duct data','Indeterminate','on');
            convert_data(get(app.DuctFileVisDropDown,'Value'));
            !mv duct/_4Unity_duct.bin ../MiniGlandVis
            close(d);
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Converting acinus data','Indeterminate','on');
            convert_data_acinus(get(app.AcinusFileVisDropDown,'Value'));
            %!mv duct/_4Unity_duct.bin ../MiniGlandVis
            close(d);
            disp('Displaying interactive animation...');
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Displaying interactive animation','Indeterminate','on');
            cd("../MiniGlandVis");
            !mini_gland.app/Contents/MacOS/MiniGland
            cd("../MiniGlandSim");
            close(d);
        end

        % Button pushed function: ResetDefaultParametersButton_2
        function ResetDefaultParametersButton_2Pushed(app, event)
            app.set_gui_parms_ini_acinus();
            app.set_parm_files_acinus();            
        end

        % Button pushed function: SaveParmsButton_2
        function SaveParmsButton_2Pushed(app, event)
            par = app.get_gui_parms_acinus;
            cd('acinus');
            save(strcat("myparms_",app.parametersfilelabelEditField_2.Value,".mat"),"par");
            cd('..');
            app.set_parm_files_acinus();
        end

        % Button pushed function: LoadParmsButton_2
        function LoadParmsButton_2Pushed(app, event)
            if strcmp(get(app.MyParmsInputDropDown_2,'Value'), '- none -')
                return;
            end
            load(strcat("acinus/", get(app.MyParmsInputDropDown_2,'Value')), "par");
            app.set_gui_parms_acinus(par);

        end

        % Button pushed function: GeneratePlotsButton_2
        function GeneratePlotsButton_2Pushed(app, event)
            if strcmp(get(app.PlotsInputDropDown_2,'Value'), '- none -')
                return;
            end
            disp('Generating acinus plot(s)...');
            %p.steadystateduct = app.steadystateductCheckBox.Value;
            %p.singleductcell = app.singleductcellCheckBox.Value;
            %p.stimulatedduct = app.stimulatedductCheckBox.Value;
            %p.ductfluxes = app.ductfluxesCheckBox.Value;

            plot_acinus(app.PlotsInputDropDown_2.Value);
            %plot_duct(app.PlotsInputDropDown.Value, p);
            disp('Plot(s) completed.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiniGlandUIFigure = uifigure('Visible', 'off');
            app.MiniGlandUIFigure.Name = 'MiniGland';
            app.MiniGlandUIFigure.CloseRequestFcn = createCallbackFcn(app, @MiniGlandUIFigureCloseRequest, true);
            app.AcinusSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.AcinusSimulationLabel.Text = 'Acinus Simulation';
            app.DuctSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.DuctSimulationLabel.Text = 'Duct Simulation';
            app.RunSimulationButtonDuct = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonDuct.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonDuctPushed, true);
            app.RunSimulationButtonDuct.Text = 'Run Simulation';
            app.RunSimulationButtonAcinus = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonAcinus.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonAcinusPushed, true);
            app.RunSimulationButtonAcinus.Text = 'Run Simulation';
            app.ENaCconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.ENaCconductanceLabel.Text = 'ENaC conductance';
            app.EditFieldiG_ENaC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_ENaC.Limits = [0 Inf];
            app.EditFieldiG_ENaC.ValueDisplayFormat = '%11.6g';
            app.CFTRconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.CFTRconductanceLabel.Text = 'CFTR conductance';
            app.EditFieldiG_CFTR = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_CFTR.Limits = [0 Inf];
            app.EditFieldiG_CFTR.ValueDisplayFormat = '%11.6g';
            app.maximumphospholipaseCLabel = uilabel(app.MiniGlandUIFigure);
            app.maximumphospholipaseCLabel.Text = 'maximum phospholipase C';
            app.EditFieldVPLC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.CaCCconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.CaCCconductanceLabel.Text = 'CaCC conductance';
            app.EditFieldiG_CaCC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_CaCC.Limits = [0 Inf];
            app.EditFieldiG_CaCC.ValueDisplayFormat = '%11.6g';
            app.MaxiKconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.MaxiKconductanceLabel.Text = 'Maxi-K conductance';
            app.EditFieldiG_BK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_BK.Limits = [0 Inf];
            app.EditFieldiG_BK.ValueDisplayFormat = '%11.6g';
            app.KconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.KconductanceLabel.Text = 'K+ conductance';
            app.EditFieldiG_K_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_K_B.Limits = [0 Inf];
            app.EditFieldiG_K_B.ValueDisplayFormat = '%11.6g';
            app.NaconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.NaconductanceLabel.Text = 'Na+ conductance';
            app.EditFieldiG_Na_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_Na_B.Limits = [0 Inf];
            app.EditFieldiG_Na_B.ValueDisplayFormat = '%11.6g';
            app.ClconductanceLabel = uilabel(app.MiniGlandUIFigure);
            app.ClconductanceLabel.Text = 'Cl- conductance';
            app.EditFieldiG_Cl_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_Cl_B.Limits = [0 Inf];
            app.EditFieldiG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.NaKATPasedensityLabel = uilabel(app.MiniGlandUIFigure);
            app.NaKATPasedensityLabel.Text = 'NaK ATPase density';
            app.EditFieldiNKA_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldiNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.ClconductanceLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.ClconductanceLabel_2.Text = 'Cl- conductance';
            app.EditFieldiG_P_Cl = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_Cl.Limits = [0 Inf];
            app.EditFieldiG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.waterpermeabilityLabel = uilabel(app.MiniGlandUIFigure);
            app.waterpermeabilityLabel.Text = 'water permeability';
            app.EditFieldiL_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiL_A.Limits = [0 Inf];
            app.EditFieldiL_A.ValueDisplayFormat = '%11.6g';
            app.waterpermeabilityLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.waterpermeabilityLabel_2.Text = 'water permeability';
            app.EditFieldiL_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiL_B.Limits = [0 Inf];
            app.EditFieldiL_B.ValueDisplayFormat = '%11.6g';
            app.NaKATPasedensityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.NaKATPasedensityEditFieldLabel.Text = 'NaK ATPase density';
            app.EditFieldaNaK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.fractionofNaKinthebasalmembraneEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.fractionofNaKinthebasalmembraneEditFieldLabel.Text = 'fraction of NaK in the basal membrane';
            app.EditFieldbNaKf = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.maximumconductanceofCaCCEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.maximumconductanceofCaCCEditFieldLabel.Text = 'maximum conductance of CaCC';
            app.EditFieldGCl = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.basolateralmaximumconductanceofCaKCEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralmaximumconductanceofCaKCEditFieldLabel.Text = 'basolateral maximum conductance of CaKC';
            app.EditFieldGK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.apicalwaterpermeabilityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.apicalwaterpermeabilityEditFieldLabel.Text = 'apical water permeability';
            app.EditFieldLa = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.basolateralwaterpermeabilityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.basolateralwaterpermeabilityEditFieldLabel.Text = 'basolateral water permeability';
            app.EditFieldLb = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.tightjunctionalwaterpermeabilityEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.tightjunctionalwaterpermeabilityEditFieldLabel.Text = 'tight-junctional water permeability';
            app.EditFieldLt = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.cellstosimulateLabel = uilabel(app.MiniGlandUIFigure);
            app.cellstosimulateLabel.Text = 'cell(s) to simulate';
            app.C1Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C1Button.Text = 'C1';
            app.C2Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C14Button.Text = 'C14';
            app.acinussimulationfileLabel = uilabel(app.MiniGlandUIFigure);
            app.acinussimulationfileLabel.Text = 'acinus simulation file';
            app.DuctInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.DuctInputDropDown.Items = {'- none -', ''};
            app.DuctInputDropDown.Value = '- none -';
            app.EditFieldsG_ENaC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_ENaC.Limits = [0 Inf];
            app.EditFieldsG_ENaC.ValueDisplayFormat = '%11.6g';
            app.intercalatedstriatedLabel = uilabel(app.MiniGlandUIFigure);
            app.intercalatedstriatedLabel.Text = 'intercalated            striated';
            app.EditFieldsG_BK = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_BK.Limits = [0 Inf];
            app.EditFieldsG_BK.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_K_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_K_B.Limits = [0 Inf];
            app.EditFieldsG_K_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Na_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_Na_B.Limits = [0 Inf];
            app.EditFieldsG_Na_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Cl_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_Cl_B.Limits = [0 Inf];
            app.EditFieldsG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNKA_alpha_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldsNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsL_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsL_A.Limits = [0 Inf];
            app.EditFieldsL_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_P_Cl = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_Cl.Limits = [0 Inf];
            app.EditFieldsG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.EditFieldsL_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsL_B.Limits = [0 Inf];
            app.EditFieldsL_B.ValueDisplayFormat = '%11.6g';
            app.GeneratePlotsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.ductfluxesCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.ductfluxesCheckBox.Text = 'duct fluxes';
            app.steadystateductCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.steadystateductCheckBox.Text = 'steady state duct';
            app.EditFieldsG_P_Na = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_Na.Limits = [0 Inf];
            app.EditFieldsG_P_Na.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_P_K = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_P_K.Limits = [0 Inf];
            app.EditFieldsG_P_K.ValueDisplayFormat = '%11.6g';
            app.NaconductanceLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.NaconductanceLabel_2.Text = 'Na+ conductance';
            app.EditFieldiG_P_Na = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_Na.Limits = [0 Inf];
            app.EditFieldiG_P_Na.ValueDisplayFormat = '%11.6g';
            app.KconductanceLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.KconductanceLabel_2.Text = 'K+ conductance';
            app.EditFieldiG_P_K = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiG_P_K.Limits = [0 Inf];
            app.EditFieldiG_P_K.ValueDisplayFormat = '%11.6g';
            app.resultsfilelabelEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.resultsfilelabelEditFieldLabel.Text = 'results file label';
            app.resultsfilelabelEditField = uieditfield(app.MiniGlandUIFigure, 'text');
            app.ductsimulationfileLabel = uilabel(app.MiniGlandUIFigure);
            app.ductsimulationfileLabel.Text = 'duct simulation file';
            app.PlotsInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.PlotsInputDropDown.Items = {'- none -', ''};
            app.PlotsInputDropDown.Value = '- none -';
            app.parametersfilelabelEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.parametersfilelabelEditFieldLabel.Text = 'parameters file label';
            app.parametersfilelabelEditField = uieditfield(app.MiniGlandUIFigure, 'text');
            app.parametersfileLabel = uilabel(app.MiniGlandUIFigure);
            app.parametersfileLabel.Text = 'parameters file';
            app.MyParmsInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.MyParmsInputDropDown.Items = {'- none -', ''};
            app.MyParmsInputDropDown.Value = '- none -';
            app.SaveParmsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.SaveParmsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParmsButtonPushed, true);
            app.SaveParmsButton.Text = 'Save';
            app.LoadParmsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.LoadParmsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParmsButtonPushed, true);
            app.LoadParmsButton.Text = 'Load';
            app.ductsimulationtypeButtonGroup = uibuttongroup(app.MiniGlandUIFigure);
            app.ductsimulationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ductsimulationtypeButtonGroupSelectionChanged, true);
            app.ductsimulationtypeButtonGroup.Title = 'duct simulation type';
            app.ExVivoButton = uiradiobutton(app.ductsimulationtypeButtonGroup);
            app.ExVivoButton.Text = 'Ex-Vivo';
            app.ExVivoButton.Value = true;
            app.InVivoButton = uiradiobutton(app.ductsimulationtypeButtonGroup);
            app.InVivoButton.Text = 'In-Vivo';
            app.ResetDefaultParametersButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.ResetDefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultParametersButtonPushed, true);
            app.ResetDefaultParametersButton.Text = 'Reset Default Parameters';
            app.VisSimulationResultsButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.VisSimulationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisSimulationResultsButtonPushed, true);
            app.VisSimulationResultsButton.Text = 'Display Interactive Animation';
            app.acinussimulationfileLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.acinussimulationfileLabel_2.Text = 'acinus simulation file';
            app.AcinusFileVisDropDown = uidropdown(app.MiniGlandUIFigure);
            app.AcinusFileVisDropDown.Items = {'- none -', ''};
            app.AcinusFileVisDropDown.Value = '- none -';
            app.ductsimulationfileLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.ductsimulationfileLabel_2.Text = 'duct simulation file';
            app.DuctFileVisDropDown = uidropdown(app.MiniGlandUIFigure);
            app.DuctFileVisDropDown.Items = {'- none -', ''};
            app.DuctFileVisDropDown.Value = '- none -';
            app.cmsLabel = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel.Text = 'cm/s';
            app.cmsLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_2.Text = 'cm/s';
            app.singleductcellCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.singleductcellCheckBox.Text = 'single duct cell';
            app.singleductcellCheckBox.Value = true;
            app.stimulatedductCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.stimulatedductCheckBox.Text = 'stimulated duct';
            app.cmsLabel_3 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_3.Text = 'cm/s';
            app.cmsLabel_4 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_4.Text = 'cm/s';
            app.cmsLabel_5 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_5.Text = 'cm/s';
            app.cmsLabel_6 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_6.Text = 'cm/s';
            app.cmsLabel_7 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_7.Text = 'cm/s';
            app.molcm2Label = uilabel(app.MiniGlandUIFigure);
            app.molcm2Label.Text = 'mol/cm^2';
            app.cmsLabel_8 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_8.Text = 'cm/s';
            app.cmsLabel_9 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_9.Text = 'cm/s';
            app.cmsLabel_10 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_10.Text = 'cm/s';
            app.cmsLabel_11 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_11.Text = 'cm/s';
            app.cmsLabel_12 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_12.Text = 'cm/s';
            app.EditFieldsAE_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsAE_G_A.Limits = [0 Inf];
            app.EditFieldsAE_G_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNBC_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNBC_G_A.Limits = [0 Inf];
            app.EditFieldsNBC_G_A.ValueDisplayFormat = '%11.6g';
            app.molcm2Label_7 = uilabel(app.MiniGlandUIFigure);
            app.molcm2Label_7.Text = 'mol/cm^2';
            app.molseccm2Label = uilabel(app.MiniGlandUIFigure);
            app.molseccm2Label.Text = 'mol/sec/cm^2';
            app.molseccm2Label_2 = uilabel(app.MiniGlandUIFigure);
            app.molseccm2Label_2.Text = 'mol/sec/cm^2';
            app.anionexchangerdensityLabel = uilabel(app.MiniGlandUIFigure);
            app.anionexchangerdensityLabel.Text = 'anion exchanger density';
            app.EditFieldiAE_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiAE_G_A.Limits = [0 Inf];
            app.EditFieldiAE_G_A.ValueDisplayFormat = '%11.6g';
            app.NBCcotransporterdensityLabel = uilabel(app.MiniGlandUIFigure);
            app.NBCcotransporterdensityLabel.Text = 'NBC cotransporter density';
            app.EditFieldiNBC_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNBC_G_A.Limits = [0 Inf];
            app.EditFieldiNBC_G_A.ValueDisplayFormat = '%11.6g';
            app.NHEcotransporterdensityLabel = uilabel(app.MiniGlandUIFigure);
            app.NHEcotransporterdensityLabel.Text = 'NHE cotransporter density';
            app.EditFieldiNHE_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNHE_G_A.Limits = [0 Inf];
            app.EditFieldiNHE_G_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsAE_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsAE_G_B.Limits = [0 Inf];
            app.EditFieldsAE_G_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNBC_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNBC_G_B.Limits = [0 Inf];
            app.EditFieldsNBC_G_B.ValueDisplayFormat = '%11.6g';
            app.molcm2Label_5 = uilabel(app.MiniGlandUIFigure);
            app.molcm2Label_5.Text = 'mol/cm^2';
            app.molseccm2Label_3 = uilabel(app.MiniGlandUIFigure);
            app.molseccm2Label_3.Text = 'mol/sec/cm^2';
            app.molseccm2Label_4 = uilabel(app.MiniGlandUIFigure);
            app.molseccm2Label_4.Text = 'mol/sec/cm^2';
            app.anionexchangerdensityLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.anionexchangerdensityLabel_2.Text = 'anion exchanger density';
            app.EditFieldiAE_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiAE_G_B.Limits = [0 Inf];
            app.EditFieldiAE_G_B.ValueDisplayFormat = '%11.6g';
            app.NBCcotransporterdensityLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.NBCcotransporterdensityLabel_2.Text = 'NBC cotransporter density';
            app.EditFieldiNBC_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNBC_G_B.Limits = [0 Inf];
            app.EditFieldiNBC_G_B.ValueDisplayFormat = '%11.6g';
            app.NHEcotransporterdensityLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.NHEcotransporterdensityLabel_2.Text = 'NHE cotransporter density';
            app.EditFieldiNHE_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldiNHE_G_B.Limits = [0 Inf];
            app.EditFieldiNHE_G_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_CaCC = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_CaCC.Limits = [0 Inf];
            app.EditFieldsG_CaCC.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_CFTR = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsG_CFTR.Limits = [0 Inf];
            app.EditFieldsG_CFTR.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNHE_G_A = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNHE_G_A.Limits = [0 Inf];
            app.EditFieldsNHE_G_A.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNHE_G_B = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.EditFieldsNHE_G_B.Limits = [0 Inf];
            app.EditFieldsNHE_G_B.ValueDisplayFormat = '%11.6g';
            app.ApicalLabel = uilabel(app.MiniGlandUIFigure);
            app.ApicalLabel.Text = 'Apical';
            app.BasolateralLabel = uilabel(app.MiniGlandUIFigure);
            app.BasolateralLabel.Text = 'Basolateral';
            app.TightjunctionalLabel = uilabel(app.MiniGlandUIFigure);
            app.TightjunctionalLabel.Text = 'Tight-junctional ';
            app.Label_4 = uilabel(app.MiniGlandUIFigure);
            app.Label_4.Text = '_____________________________________________________________________________________';
            app.Label_5 = uilabel(app.MiniGlandUIFigure);
            app.Label_5.Text = '_____________________________________________________________________________________';
            app.Label_6 = uilabel(app.MiniGlandUIFigure);
            app.Label_6.Text = '_____________________________________________________________________________________';
            app.Label_7 = uilabel(app.MiniGlandUIFigure);
            app.Label_7.Text = '_____________________________________________________________________________________';
            app.Label_8 = uilabel(app.MiniGlandUIFigure);
            app.Label_8.Text = '_____________________________________________________________________________________';
            app.Label_9 = uilabel(app.MiniGlandUIFigure);
            app.Label_9.Text = '_____________________________________________________________________________________';
            app.SimulationResultsVisualisationLabel = uilabel(app.MiniGlandUIFigure);
            app.SimulationResultsVisualisationLabel.Text = 'Simulation Results Visualisation';
            app.Label_10 = uilabel(app.MiniGlandUIFigure);
            app.Label_10.Text = '______________________________________________________________';
            app.GeneratePlotsButton_2 = uibutton(app.MiniGlandUIFigure, 'push');
            app.GeneratePlotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButton_2Pushed, true);
            app.GeneratePlotsButton_2.Text = 'Generate Plots';
            app.acinuslumenfluidCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.acinuslumenfluidCheckBox.Text = 'acinus lumen fluid';
            app.acinuslumenfluidCheckBox.Value = true;
            app.plot3CheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.plot3CheckBox.Text = {'plot 3'; ''};
            app.plot2CheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.plot2CheckBox.Text = 'plot 2';
            app.plot4CheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.plot4CheckBox.Text = 'plot 4';
            app.resultsfilelabelEditField_2Label = uilabel(app.MiniGlandUIFigure);
            app.resultsfilelabelEditField_2Label.Text = 'results file label';
            app.resultsfilelabelEditField_2 = uieditfield(app.MiniGlandUIFigure, 'text');
            app.acinussimulationfileLabel_3 = uilabel(app.MiniGlandUIFigure);
            app.acinussimulationfileLabel_3.Text = 'acinus simulation file';
            app.PlotsInputDropDown_2 = uidropdown(app.MiniGlandUIFigure);
            app.PlotsInputDropDown_2.Items = {'- none -', ''};
            app.PlotsInputDropDown_2.Value = '- none -';
            app.SaveParmsButton_2 = uibutton(app.MiniGlandUIFigure, 'push');
            app.SaveParmsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveParmsButton_2Pushed, true);
            app.SaveParmsButton_2.Text = 'Save';
            app.LoadParmsButton_2 = uibutton(app.MiniGlandUIFigure, 'push');
            app.LoadParmsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadParmsButton_2Pushed, true);
            app.LoadParmsButton_2.Text = 'Load';
            app.parametersfilelabelEditField_2Label = uilabel(app.MiniGlandUIFigure);
            app.parametersfilelabelEditField_2Label.Text = 'parameters file label';
            app.parametersfilelabelEditField_2 = uieditfield(app.MiniGlandUIFigure, 'text');
            app.parametersfileLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.parametersfileLabel_2.Text = 'parameters file';
            app.MyParmsInputDropDown_2 = uidropdown(app.MiniGlandUIFigure);
            app.MyParmsInputDropDown_2.Items = {'- none -', ''};
            app.MyParmsInputDropDown_2.Value = '- none -';
            app.Label_11 = uilabel(app.MiniGlandUIFigure);
            app.Label_11.Text = '_____________________________________________________________________________________';
            app.ResetDefaultParametersButton_2 = uibutton(app.MiniGlandUIFigure, 'push');
            app.ResetDefaultParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultParametersButton_2Pushed, true);
            app.ResetDefaultParametersButton_2.Text = 'Reset Default Parameters';
            app.molcm2Label_8 = uilabel(app.MiniGlandUIFigure);
            app.molcm2Label_8.Text = 'mol/cm^2';
            app.cmsLabel_13 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_13.Text = 'cm/s';
            app.cmsLabel_14 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_14.Text = 'cm/s';
            app.cmsLabel_15 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_15.Text = 'cm/s';
            app.cmsLabel_16 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_16.Text = 'cm/s';
            app.cmsLabel_17 = uilabel(app.MiniGlandUIFigure);
            app.cmsLabel_17.Text = 'cm/s';
            app.Label_12 = uilabel(app.MiniGlandUIFigure);
            app.Label_12.Text = '-';
            app.molcm2Label_9 = uilabel(app.MiniGlandUIFigure);
            app.molcm2Label_9.Text = 'mol/cm^2';
            % Show the figure after all components are created
            app.MiniGlandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_gland_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiniGlandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiniGlandUIFigure)
        end
    end
end